classdef brandDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadButton                     matlab.ui.control.Button
        BrandEditFieldLabel            matlab.ui.control.Label
        BrandEditField                 matlab.ui.control.EditField
        PredefinedVideosDropDownLabel  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        GetBrandButton                 matlab.ui.control.Button
        FinishedLampLabel              matlab.ui.control.Label
        FinishedLamp                   matlab.ui.control.Lamp
        BrandAxes                      matlab.ui.control.UIAxes
        ImageAxes                      matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
        function updateimage(app, imagefile)
            
            % For skoda.png, read the second image in the file 
            if strcmp(imagefile,'skoda.png')
                im = imread('skoda.png');
            elseif strcmp(imagefile,'rangeRover.png')
                im = imread('rangeRover.png');
            elseif strcmp(imagefile, 'toyota.png')
                im = imread('toyota.png');
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end   
            end
            imagesc(app.ImageAxes,im);
        end
        
        
        %%output matched brand
        function updatebrand(app, image)
            app.FinishedLamp.Color = [0.39,0.83,0.07];
            % load logo picture into Image
            imagesc(app.BrandAxes,image);
        end
        
 
        %%find logo position (Kevin Baur 11827180)
        function logoPosition(app, im)
            croppedImage = logoPosition(im);
            brandMatching(app,croppedImage);
        end
        
        
        %%brand matching (Manuel Lautschacher 11822449) 
        function brandMatching(app,im)
            [brandImage, brandname] = matching(im);
            %imshow(brand);
            app.BrandEditField.Value = brandname; %%updates brand in textfield GUI
            
            %%updates brand image in GUI
            updatebrand(app, brandImage);
        end
        
        %%Videos to frames (Jan König 01007167)
        function Video2Frames(app, video)
            % folderS "VideoFiles" & "Frames" needs to be in working directory
            
            % iterating each frame and writing it to Frames/ as framex.png
            for img = 1:video.NumFrames
                filename = strcat('Frames/frame', num2str(img), '.png');
                tempframe = read(video, img); 
                imwrite(tempframe, filename);
            end
            logoPosition(app,tempframe);
        end
        
        %%car detection (Lucio Delen 11713066) 
        function carDetection(app, video)
            
                
        end
        
        %%detects stopped car (Vincent Zoechling 11913652) 
        function stopDetection(app, video)
            
            end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image
            updateimage(app, 'suzuki.png');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.png;'};
            f = uigetfile(filterspec);
            %v = Videoreader(f);
            %while hasFrame(v)
            %imshow(readFrame(v), 'Parent', app.ImageAxes);
            %end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
             % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: GetBrandButton
        function GetBrandButtonPushed(app, event)
            app.FinishedLamp.Color = [1.00,0.41,0.16];
            video = imread(app.DropDown.Value);
            %Video2Frames(app,video);
            logoPosition(app,video);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Video';
            app.BrandEditFieldLabel = uilabel(app.UIFigure);
            app.BrandEditFieldLabel.Text = 'Brand';
            app.BrandEditField = uieditfield(app.UIFigure, 'text');
            app.PredefinedVideosDropDownLabel = uilabel(app.UIFigure);
            app.PredefinedVideosDropDownLabel.Text = 'Predefined Videos';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'car one', 'car two', 'car three', 'car four'};
            app.DropDown.ItemsData = {'suzuki.png', 'skoda.png', 'rangeRover.png', 'toyota.png'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'suzuki.png';
            app.GetBrandButton = uibutton(app.UIFigure, 'push');
            app.GetBrandButton.ButtonPushedFcn = createCallbackFcn(app, @GetBrandButtonPushed, true);
            app.GetBrandButton.Text = 'Get Brand';
            app.FinishedLampLabel = uilabel(app.UIFigure);
            app.FinishedLampLabel.Text = 'Finished';
            app.FinishedLamp = uilamp(app.UIFigure);
            app.BrandAxes = uiaxes(app.UIFigure);
            app.BrandAxes.XTick = [];
            app.BrandAxes.XTickLabel = '';
            app.BrandAxes.YTick = [];
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brandDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end